classdef RecordGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RecordGUIFigure          matlab.ui.Figure
        CalculateButton1         matlab.ui.control.Button
        ParametersPanel          matlab.ui.container.Panel
        ParamAnalysisEditField   matlab.ui.control.EditField
        ParamDisplayDropDown     matlab.ui.control.DropDown
        DisplayLabel             matlab.ui.control.Label
        ParamAnalysisDropDown    matlab.ui.control.DropDown
        AnalysisLabel            matlab.ui.control.Label
        ParamDisplayEditField    matlab.ui.control.EditField
        OutputButton1            matlab.ui.control.Button
        ReanalyzeButton          matlab.ui.control.Button
        ExportButton_Data        matlab.ui.control.Button
        ExportButton_Plot        matlab.ui.control.Button
        PlotDropDown             matlab.ui.control.DropDown
        ExperimentPanel          matlab.ui.container.Panel
        ExperimentButton_SaveAs  matlab.ui.control.Button
        ExperimentButton_Save    matlab.ui.control.Button
        ExperimentButton_Load    matlab.ui.control.Button
        ExperimentButton_New     matlab.ui.control.Button
        RecordPanel              matlab.ui.container.Panel
        RecordCheckBox           matlab.ui.control.CheckBox
        RecordButton_Remove      matlab.ui.control.Button
        RecordButton_Add         matlab.ui.control.Button
        RecordListBox            matlab.ui.control.ListBox
        GroupPanel               matlab.ui.container.Panel
        GroupListBox             matlab.ui.control.ListBox
        GroupCheckBox            matlab.ui.control.CheckBox
        GroupButton_Down         matlab.ui.control.Button
        GroupButton_Up           matlab.ui.control.Button
        GroupEditField_Rename    matlab.ui.control.EditField
        RenameLabel              matlab.ui.control.Label
        GroupButton_Delete       matlab.ui.control.Button
        GroupButton_New          matlab.ui.control.Button
        UIAxes_Plot              matlab.ui.control.UIAxes
        UIAxes_Display           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        IDs = struct;
        File = struct;
        Experiment = struct;
        Record = struct;
        Flags = struct;
        Parameters = struct;
        Specs = struct; % structure from external call
    end
    
    methods (Access = private)
        
        function OK = RecordAdd(app)
            OK = 1;
            if app.File.Record.Path ~= 0
                cd(app.File.Record.Path);
                Group = app.Experiment.Groups(app.IDs.Group).Group;
                pathname = char(app.File.Record.Path);
                name = char(app.File.Record.Name);
                check = strmatch(name, Group.RecordList);
                if isempty(check)
                    app.Record = [];
                    app.Record.Name = name;
                    app.Record.Path = pathname;
                    app.Record.Flags.Anal = 0;
                    Group.NumRecs = Group.NumRecs + 1;
                    Group.Records(Group.NumRecs).Record = app.Record;
                    Group.RecordList(Group.NumRecs) = {app.Record.Name};
                    [Group.RecordList, indsrt] = sort(Group.RecordList);
                    app.IDs.Record = find(indsrt==Group.NumRecs);
                    Group.Records = Group.Records(indsrt);
                    app.Experiment.Groups(app.IDs.Group).Group = Group;
                    Source = app.Specs.Functions.RecordLoad(app.Experiment, app.IDs);
                    app.Experiment.Groups(app.IDs.Group).Group.Records(app.IDs.Record).Record.Data.Source = Source;
                    app.RecordListBox.Items = Group.RecordList;
                    app.RecordListBox.Value = app.RecordListBox.Items(app.IDs.Record);
                    app.RecordGUIFigure.Name = ['*' app.Experiment.Name];
                    app.Specs.Functions.RecordDisplay(app, app.UIAxes_Display);
                    RecordAnalyze(app);
                    RecordListRefresh(app);
                else
                    OK = 0;
                end
            end            
        end
        
        function RecordRemove(app)
            Group = app.Experiment.Groups(app.IDs.Group).Group;
            Group.NumRecs = Group.NumRecs-1;
            if ~Group.NumRecs
                Group.RecordList = {};
                Group.Records = [];
                Group.NumAnalyzed = 0;
                app.Record = [];
                app.IDs.Record = 0;
            else
                if app.IDs.Record <= Group.NumRecs
                    for j = app.IDs.Record:Group.NumRecs
                        Group.RecordList{j} = Group.RecordList{j+1};
                        Group.Records(j).Record = Group.Records(j+1).Record;
                    end
                else
                    app.IDs.Record = Group.NumRecs;
                end
                if app.Record.Flags.Anal
                    Group.NumAnalyzed = Group.NumAnalyzed-1;
                end
                Group.RecordList = Group.RecordList(1:Group.NumRecs);
                Group.Records = Group.Records(1:Group.NumRecs);
                app.Record = Group.Records(app.IDs.Record).Record;
            end
            app.Experiment.Groups(app.IDs.Group).Group = Group;
            app.RecordGUIFigure.Name = ['*' app.Experiment.Name];
            RecordListRefresh(app);
            
        end
        
        function RecordListRefresh(app)
            if app.Experiment.NumGrps
                Group = app.Experiment.Groups(app.IDs.Group).Group;
                app.RecordListBox.Items = Group.RecordList;
                app.RecordButton_Add.Enable = 1;
                if Group.NumRecs>0
                    app.RecordListBox.Value = app.RecordListBox.Items(app.IDs.Record);
                    app.RecordButton_Remove.Enable = 1;
                    app.Record = Group.Records(app.IDs.Record).Record;
                    app.Specs.Functions.RecordDisplay(app, app.UIAxes_Display);
                    app.Specs.Functions.RecordPlot(app.Experiment, app.UIAxes_Plot, app.PlotDropDown.Value, app.IDs);
                    app.RecordCheckBox.Enable = 1;
                    if isfield(app.Record.Flags,'In') && app.Record.Flags.In==1 || ~ isfield(app.Record.Flags,'In')
                        app.RecordCheckBox.Value = 1;
                    else
                        app.RecordCheckBox.Value = 0;
                    end

                else
                    app.RecordButton_Remove.Enable = 0;
                    app.RecordCheckBox.Enable = 0;
                    app.RecordCheckBox.Value = 1;
                    app.Record = [];
                    app.IDs.Record = 0;
                    cla(app.UIAxes_Display);
                end
            else
                app.RecordButton_Add.Enable = 0;
                app.RecordButton_Remove.Enable = 0;
                app.RecordCheckBox.Enable = 0;
                app.Record = [];
                app.IDs.Record = 0;
                cla(app.UIAxes_Display);
            end
        end
        
        function GroupControls(app)
            app.GroupButton_New.Enable = 1;
            if app.Experiment.NumGrps>0
                state = 1;
            else
                state = 0;
                app.GroupEditField_Rename.Value = '';
            end
            app.GroupButton_Delete.Enable = state;
            app.GroupButton_Up.Enable = state;
            app.GroupButton_Down.Enable = state;
            app.GroupEditField_Rename.Enable = state;
            app.RecordButton_Add.Enable = state;
        end
        
        function RecordControls(app)
            if app.Experiment.NumGrps>0
                Group = app.Experiment.Groups(app.IDs.Group).Group;
                if Group.NumRecs>0
                    state = 1;
                else
                    state = 0;
                end
                app.RecordButton_Remove.Enable = state;
                app.RecordCheckBox.Enable = state;
            end
        end
        
        function ParameterControls(app)
            % Update parameter edit fields according to selected paramter
            % from dropdown menue
            if ~isempty(app.Experiment.Specs.Analysis.Parameters)
                AnalysisType = app.ParamAnalysisDropDown.Value;
                if isfield(app.Experiment.Specs.Analysis.Parameters,AnalysisType)
                    AnalysisValue = eval(sprintf('app.Experiment.Specs.Analysis.Parameters.%s',AnalysisType));
                else
                    AnalysisValue = eval(sprintf('app.Specs.Analysis.Parameters.%s',AnalysisType));
                    eval(sprintf('app.Experiment.Specs.Analysis.Parameters.%s=%g;',AnalysisType,AnalysisValue));
                end
                app.ParamAnalysisEditField.Value = num2str(AnalysisValue);
            end
            if ~isempty(app.Experiment.Specs.Display.Parameters)
                DisplayType = app.ParamDisplayDropDown.Value;
                DisplayValue = eval(sprintf('app.Experiment.Specs.Display.Parameters.%s',DisplayType));
                app.ParamDisplayEditField.Value = num2str(DisplayValue);            
            end
        end

        
        function ParametersSave(app)
            Parameters = app.Parameters;
            save(app.File.Parameters.Fullfile, '-struct', 'Parameters');
        end
        
        function ParametersLoad(app)
            app.Parameters = load(app.File.Parameters.Fullfile);
        end
        
        function RecordAnalyze(app)
            Score = app.Specs.Functions.RecordAnalyze(app, app.UIAxes_Display);
            app.Experiment.Groups(app.IDs.Group).Group.Records(app.IDs.Record).Record.Data.Score = Score;
            app.Experiment.Groups(app.IDs.Group).Group.Records(app.IDs.Record).Record.Flags.Anal = 1;
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % locate app's path and filename
            [app.File.App.Path, app.File.App.Name] = fileparts(mfilename("fullpath"));
            % locate and load parameter file
            app.File.Parameters.Fullfile = fullfile(app.File.App.Path,[app.File.App.Name,'_Parameters']);
            ParametersLoad(app);
            % Experiment type
            switch nargin
                case 1
                app.ExperimentPanel.Title = 'Experiment';
                case 2
                app.Specs.Name = varargin{1};
                app.ExperimentPanel.Title = sprintf('Experiment: %s', app.Specs.Name);
                case 3
                Components = varargin{2};
                % Type-specific components
                N = numel(Components);
                for i=1:N
                    C = Components(i);
                    CNameStr = ['app.',C.Name];
                    M = numel(C.Properties);
                    for j=1:M
                        ValueStr = C.Values{j};
                        SetStr = [CNameStr,'.',C.Properties{j},'=',ValueStr,';'];
                        eval(SetStr);                    
                    end
                end
                app.Specs.Components = Components;
            end
        end

        % Button pushed function: GroupButton_New
        function GroupButton_NewPushed(app, event)
            if app.File.Exp.Name ~= 0
                app.Experiment.NumGrps = app.Experiment.NumGrps+1;
                app.IDs.Group = app.Experiment.NumGrps;
%                 app.IDs.Frame = 1;
                Group = [];
                Group.Name = sprintf('Group %g', app.Experiment.NumGrps);
                Group.NumRecs = 0;
                Group.NumAnalyzed = 0;
                Group.RecordList = {};
                Group.Records = [];
                Group.Summary = [];
                app.Experiment.GroupList(app.IDs.Group) = {Group.Name};
                app.Experiment.Groups(app.IDs.Group).Group = Group;
                app.GroupListBox.Items = app.Experiment.GroupList;
                app.GroupListBox.Value = app.Experiment.GroupList{app.IDs.Group};
                app.GroupEditField_Rename.Value = app.GroupListBox.Value;
% % %                 app.RecordListBox.Items = Group.RecordList;
                app.RecordGUIFigure.Name = ['*' app.Experiment.Name];
                GroupControls(app);
                RecordControls(app);
                RecordListRefresh(app);
            end
        end

        % Button pushed function: GroupButton_Delete
        function GroupButton_DeletePushed(app, event)
            Confirm = questdlg('Are you sure you want to delete the group and all its records?');
            if strcmpi(Confirm, 'YES')
                app.IDs.Group = find(strcmp(app.GroupListBox.Items, app.GroupListBox.Value));
                app.Experiment.NumGrps = app.Experiment.NumGrps-1;
                if ~app.Experiment.NumGrps
                    app.Experiment.GroupList = {};
                    app.Experiment.Groups = [];
                    app.IDs.Group = 0;
                    app.GroupEditField_Rename.Value = '';
                    app.GroupButton_Delete.Enable = 0;
                else
                    if app.IDs.Group <= app.Experiment.NumGrps
                        for j = app.IDs.Group:app.Experiment.NumGrps
                            app.Experiment.GroupList{j} = app.Experiment.GroupList{j+1};
                            app.Experiment.Groups(j).Group = app.Experiment.Groups(j+1).Group;
                        end
                    else
                        app.IDs.Group = app.Experiment.NumGrps;
                    end
                    app.Experiment.GroupList = app.Experiment.GroupList(1:app.Experiment.NumGrps);
                    app.Experiment.Groups = app.Experiment.Groups(1:app.Experiment.NumGrps);
                    app.IDs.Record = 1;
                    app.GroupListBox.Value = app.Experiment.GroupList{app.IDs.Group};
                end
                app.GroupListBox.Items = app.Experiment.GroupList;
                app.RecordGUIFigure.Name = ['*' app.Experiment.Name];
                GroupControls(app);
                RecordControls(app);
                RecordListRefresh(app);
            end
        end

        % Value changed function: GroupListBox
        function GroupListBoxValueChanged(app, event)
            app.IDs.Group = find(strcmp(app.GroupListBox.Items, app.GroupListBox.Value));
            app.IDs.Record = 1;
%             app.IDs.Frame = 1;
            RecordListRefresh(app);
            GroupControls(app);
            RecordControls(app);
            app.GroupEditField_Rename.Value = app.GroupListBox.Value;
        end

        % Value changed function: GroupEditField_Rename
        function GroupEditField_RenameValueChanged(app, event)
            if app.Experiment.NumGrps
                newname = app.GroupEditField_Rename.Value;
                if find(strcmp(app.GroupListBox.Items, newname))
                    errordlg('This name is already taken')
                    app.GroupEditField_Rename.Value = app.GroupListBox.Value;
                else
                    app.IDs.Group = find(strcmp(app.GroupListBox.Items, app.GroupListBox.Value));
                    app.Experiment.Groups(app.IDs.Group).Group.Name = newname;
                    app.Experiment.GroupList{app.IDs.Group} = newname;
                    app.GroupListBox.Items = app.Experiment.GroupList;
                    app.GroupListBox.Value = app.Experiment.GroupList{app.IDs.Group};
                    app.RecordGUIFigure.Name = ['*' app.Experiment.Name];
                end
            end            
        end

        % Button pushed function: GroupButton_Up
        function GroupButton_UpPushed(app, event)
            if app.Experiment.NumGrps
                if app.IDs.Group>1
                    Group = app.Experiment.Groups(app.IDs.Group).Group;
                    app.Experiment.Groups(app.IDs.Group).Group = app.Experiment.Groups(app.IDs.Group-1).Group;
                    app.Experiment.GroupList{app.IDs.Group} = app.Experiment.Groups(app.IDs.Group-1).Group.Name;
                    app.IDs.Group = app.IDs.Group-1;
                    app.Experiment.Groups(app.IDs.Group).Group = Group;
                    app.Experiment.GroupList{app.IDs.Group} = Group.Name;
                    app.GroupListBox.Items = app.Experiment.GroupList;
                    app.GroupListBox.Value = app.Experiment.GroupList{app.IDs.Group};
                    app.GroupEditField_Rename.Value = app.GroupListBox.Value;
                    app.RecordGUIFigure.Name = ['*' app.Experiment.Name];
                end
            end
            
        end

        % Button pushed function: GroupButton_Down
        function GroupButton_DownPushed(app, event)
            if app.Experiment.NumGrps
                if app.IDs.Group<app.Experiment.NumGrps
                    Group = app.Experiment.Groups(app.IDs.Group).Group;
                    app.Experiment.Groups(app.IDs.Group).Group = app.Experiment.Groups(app.IDs.Group+1).Group;
                    app.Experiment.GroupList{app.IDs.Group} = app.Experiment.Groups(app.IDs.Group+1).Group.Name;
                    app.IDs.Group = app.IDs.Group+1;
                    app.Experiment.Groups(app.IDs.Group).Group = Group;
                    app.Experiment.GroupList{app.IDs.Group} = Group.Name;
                    app.GroupListBox.Items = app.Experiment.GroupList;
                    app.GroupListBox.Value = app.Experiment.GroupList{app.IDs.Group};
                    app.GroupEditField_Rename.Value = app.GroupListBox.Value;
                    app.RecordGUIFigure.Name = ['*' app.Experiment.Name];
                end
            end            
        end

        % Value changed function: GroupCheckBox
        function GroupCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: RecordButton_Add
        function RecordButton_AddPushed(app, event)
            button = questdlg('Load single record or batch?','Load type','Single','Batch','Single');
            if strcmp(button, 'Single')
                if isfield(app.Specs.Records,'uiget') & strcmp(app.Specs.Records.uiget,'dir')
                    app.File.Record.Path = uigetdir;
                    idcs   = strfind(app.File.Record.Path,filesep);
                    app.File.Record.Name = app.File.Record.Path(idcs(end)+1:end);
                else
                    [app.File.Record.Name, app.File.Record.Path] = uigetfile(['*.', app.Specs.Records.FileType], 'Please select record file');
                end
                OK = RecordAdd(app);
                if ~OK
                    errordlg('Record already exists in the record list!','Attempt to add an existing record');
                end
            else
                BatchPath = uigetdir;
                if BatchPath ~= 0
                    app.File.Record.Path = BatchPath;
                    BatchDir = dir(BatchPath);
                    BatchList = {BatchDir.name};
                    RecordFind = strfind(BatchList, app.Specs.Records.FileType);
                    NumRecords = numel(BatchList);
                    OK = 1;
                    RecordInd = [];
                    for r = 1:NumRecords
                        if RecordFind{r}
                            RecordInd = [RecordInd r];
                        end
                    end
                    BatchList = BatchList(RecordInd);
                    NumRecords = numel(BatchList);
                    if OK
                        for r = 1:NumRecords
                            app.File.Record.Name = BatchList{r};
                            RecordAdd(app);
                        end        
                    else
                        errordlg('Please select a directory of records for batch loading');
                    end
                end
            end
            RecordControls(app);
        end

        % Button pushed function: RecordButton_Remove
        function RecordButton_RemovePushed(app, event)
            Confirm = '';
            app.IDs.Record = find(strcmp(app.RecordListBox.Items, app.RecordListBox.Value));
            Group = app.Experiment.Groups(app.IDs.Group).Group;
            if Group.NumRecs
                Confirm = questdlg('Are you sure you want to remove this record?');
            end
            if strcmpi(Confirm, 'YES')
                RecordRemove(app);
                RecordControls(app);
            end
        end

        % Value changed function: RecordListBox
        function RecordListBoxValueChanged(app, event)
            IDRec = find(strcmp(app.RecordListBox.Items, app.RecordListBox.Value));
            Group = app.Experiment.Groups(app.IDs.Group).Group;
            if ~isempty(IDRec) && IDRec<=Group.NumRecs
                app.IDs.Record = IDRec;
                app.Record = Group.Records(app.IDs.Record).Record;
                RecordListRefresh(app);
%                 app.Specs.Functions.RecordDisplay(app, app.UIAxes_Display);
%                 WE_PlotTrace(handles);
%                 WE_PlotWorm(handles);
%                 WE_PlotTrack(handles);
            else
                app.RecordListBox.Value = app.RecordListBox.Items{app.IDs.Record};
            end            
        end

        % Button pushed function: ExperimentButton_New
        function ExperimentButton_NewPushed(app, event)
            [Name, Path] = uiputfile('*.mat', 'Select a file name for the new experiment');
            if Name ~= 0
                app.File.Exp.Name = Name;
                app.File.Exp.Path = Path;
                cd(app.File.Exp.Path);
                app.Experiment = [];
                app.Experiment.Specs = app.Specs;
                app.Experiment.Name = app.File.Exp.Name;
                app.Experiment.NumGrps = 0;
                app.Experiment.GroupList = {};
                app.Experiment.Groups = [];
                app.IDs.Group = 0;
                app.IDs.Record = 0;
                app.RecordGUIFigure.Name = app.Experiment.Name;
                Experiment = app.Experiment; % the Experiment variable is used in the next line
                save([app.File.Exp.Path, app.File.Exp.Name], '-struct', 'Experiment');
                app.GroupListBox.Items = {};
                app.GroupListBox.ItemsData = [];
                app.RecordListBox.Items = {};
                app.RecordListBox.ItemsData = [];
%                 GUIList = {'pushbutton_GroupNew','pushbutton_GroupDelete', ...
%                     'pushbutton_GroupRename', 'pushbutton_RecordRemove'};
%                 State = [1 0 0 0];
%                 GUIEnable(handles,GUIList,State);
%                 app.File.Plot.Path = app.File.Exp.Path;
                GroupControls(app);
                RecordControls(app);
                ParameterControls(app);
            end
            
        end

        % Button pushed function: ExperimentButton_Load
        function ExperimentButton_LoadPushed(app, event)
            [app.File.Exp.Name, app.File.Exp.Path] = uigetfile('*.mat', 'Select experiment file');
            if app.File.Exp.Name ~= 0
                app.RecordGUIFigure.Name = app.File.Exp.Name;
                pause(0.1);
                Exp = load(fullfile(app.File.Exp.Path, app.File.Exp.Name));
                if isfield(Exp,'Name') && isfield(Exp,'NumGrps') && isfield(Exp,'Groups')
                    app.Experiment = Exp;
                    cd(app.File.Exp.Path);
%                     app.File.Plot.Path = app.File.Exp.Path;
% % %                     app.Experiment.GroupList= app.Experiment.GroupList;
                    if app.Experiment.NumGrps>0
                        app.IDs.Group = 1;
                        Group = app.Experiment.Groups(app.IDs.Group).Group;
                        app.GroupListBox.Items = app.Experiment.GroupList;
                        app.GroupListBox.Value = app.Experiment.GroupList{1};
                        app.GroupEditField_Rename.Value = app.GroupListBox.Value;
% % %                         app.GroupButton_Delete.Enable = 1;
% % %                         app.GroupEditField_Rename.Enable = 1;
                        if Group.NumRecs>0
                            app.IDs.Record = 1;
                            app.Record = Group.Records(app.IDs.Record).Record;
%                             edit_NameDepth_Callback(handles.edit_NameDepth, eventdata, handles);
%                             set(handles.pushbutton_RecordRemove, 'Enable', 'on');
% % %                             app.RecordListBox.Items = Group.RecordList;
% % %                             app.RecordListBox.Value = Group.RecordList{app.IDs.Record};                            
                        end
% % %                         FA_RecordRetrieve(handles);
                    else
% % %                         app.GroupButton_Delete.Enable = 0;
% % %                         app.GroupEditField_Rename.Enable = 0;
%                         set(handles.popupmenu_Group2Test, 'Visible', 'off');
%                         set(handles.text_Group2Test, 'Visible', 'off');
                    end
                    app.Experiment.Name = app.File.Exp.Name;
                    RecordListRefresh(app);
                    GroupControls(app);
                    RecordControls(app);
                    ParameterControls(app);
                end
            end
            
        end

        % Button pushed function: ExperimentButton_Save
        function ExperimentButton_SavePushed(app, event)
            app.RecordGUIFigure.Name = 'Saving Please Wait';
            pause(0.1);
            Experiment = app.Experiment;
            save([app.File.Exp.Path, app.File.Exp.Name], '-struct', 'Experiment');
            app.RecordGUIFigure.Name = app.Experiment.Name;            
        end

        % Button pushed function: ExperimentButton_SaveAs
        function ExperimentButton_SaveAsPushed(app, event)
            if isfield(app.File, 'Exp')
                [app.File.Exp.Name, app.File.Exp.Path] = uiputfile('*.mat', 'Save experiment as', app.File.Exp.Name);
                if app.File.Exp.Name ~= 0
                    app.Experiment.Name = app.File.Exp.Name;
                    Experiment = app.Experiment;
                    save([app.File.Exp.Path, app.File.Exp.Name], '-struct', 'Experiment');
                    app.RecordGUIFigure.Name = app.Experiment.Name;
                end
            end            
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            value = app.PlotDropDown.Value;
            app.Specs.Functions.RecordPlot(app.Experiment, app.UIAxes_Plot, value, app.IDs);
        end

        % Button pushed function: ExportButton_Plot
        function ExportButton_PlotPushed(app, event)
            figure;
            h = copyobj(app.UIAxes_Plot, gcf);
            set(h, 'Units', 'normalized', 'Position', [0.25 0.25 0.5 0.5]);
            set(h, 'FontSize', 16)
        end

        % Button pushed function: ExportButton_Data
        function ExportButton_DataPushed(app, event)
            app.Specs.Functions.ExportData(app.File, app.Experiment, app.PlotDropDown.Value);
        end

        % Button pushed function: ReanalyzeButton
        function ReanalyzeButtonPushed(app, event)
            record = app.Record;
            IDg = app.IDs.Group;
            IDr = app.IDs.Record;
            for g=1:app.Experiment.NumGrps
                Group = app.Experiment.Groups(g).Group;
                app.IDs.Group = g;
                for r=1:Group.NumRecs
                    app.Record = Group.Records(r).Record;
                    app.IDs.Record = r;
                    RecordAnalyze(app);
                end
            end
            app.IDs.Group = IDg;
            app.IDs.Record = IDr;
            app.Record = record;
            app.PlotDropDownValueChanged(app);
        end

        % Button pushed function: OutputButton1
        function OutputButton1Pushed(app, event)
            app.Specs.Functions.OutputButton1(app, app.UIAxes_Display);
        end

        % Value changed function: ParamDisplayEditField
        function ParamDisplayEditFieldValueChanged(app, event)
            DisplayValue = app.ParamDisplayEditField.Value;
            DisplayType = app.ParamDisplayDropDown.Value;
            eval(sprintf('app.Experiment.Specs.Display.Parameters.%s=%s;',DisplayType,DisplayValue));
            app.PlotDropDownValueChanged(app);
        end

        % Value changed function: ParamAnalysisDropDown
        function ParamAnalysisDropDownValueChanged(app, event)
            ParameterControls(app);
        end

        % Value changed function: ParamDisplayDropDown
        function ParamDisplayDropDownValueChanged(app, event)
            ParameterControls(app);            
        end

        % Value changed function: ParamAnalysisEditField
        function ParamAnalysisEditFieldValueChanged(app, event)
            AnalysisValue = app.ParamAnalysisEditField.Value;
            AnalysisType = app.ParamAnalysisDropDown.Value;
            eval(sprintf('app.Experiment.Specs.Analysis.Parameters.%s=%s',AnalysisType,AnalysisValue));
            ReanalyzeButtonPushed(app);
        end

        % Button pushed function: CalculateButton1
        function CalculateButton1Pushed(app, event)
            app.Specs.Functions.CalculateButton1(app, app.UIAxes_Display);
            ParameterControls(app);
        end

        % Value changed function: RecordCheckBox
        function RecordCheckBoxValueChanged(app, event)
            value = app.RecordCheckBox.Value;
            app.Experiment.Groups(app.IDs.Group).Group.Records(app.IDs.Record).Record.Flags.In = value;
            app.Record = app.Experiment.Groups(app.IDs.Group).Group.Records(app.IDs.Record).Record;
            value = app.PlotDropDown.Value;
            app.Specs.Functions.RecordPlot(app.Experiment, app.UIAxes_Plot, value, app.IDs);
            app.Specs.Functions.RecordDisplay(app, app.UIAxes_Display);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RecordGUIFigure = uifigure('Visible', 'off');
            app.RecordGUIFigure.Name = 'Record GUI';
            app.UIAxes_Display = uiaxes(app.RecordGUIFigure);
            app.UIAxes_Display.XTick = [];
            app.UIAxes_Display.YTick = [];
            app.UIAxes_Display.Visible = 'off';
            app.UIAxes_Plot = uiaxes(app.RecordGUIFigure);
            app.UIAxes_Plot.XTick = [];
            app.UIAxes_Plot.YTick = [];
            app.UIAxes_Plot.Visible = 'off';
            app.GroupPanel = uipanel(app.RecordGUIFigure);
            app.GroupPanel.Title = 'Group';
            app.GroupButton_New = uibutton(app.GroupPanel, 'push');
            app.GroupButton_New.ButtonPushedFcn = createCallbackFcn(app, @GroupButton_NewPushed, true);
            app.GroupButton_New.Enable = 'off';
            app.GroupButton_New.Text = 'New';
            app.GroupButton_Delete = uibutton(app.GroupPanel, 'push');
            app.GroupButton_Delete.ButtonPushedFcn = createCallbackFcn(app, @GroupButton_DeletePushed, true);
            app.GroupButton_Delete.Enable = 'off';
            app.GroupButton_Delete.Text = 'Delete';
            app.RenameLabel = uilabel(app.GroupPanel);
            app.RenameLabel.Enable = 'off';
            app.RenameLabel.Text = 'Rename';
            app.GroupEditField_Rename = uieditfield(app.GroupPanel, 'text');
            app.GroupEditField_Rename.ValueChangedFcn = createCallbackFcn(app, @GroupEditField_RenameValueChanged, true);
            app.GroupEditField_Rename.Enable = 'off';
            app.GroupButton_Up = uibutton(app.GroupPanel, 'push');
            app.GroupButton_Up.ButtonPushedFcn = createCallbackFcn(app, @GroupButton_UpPushed, true);
            app.GroupButton_Up.Enable = 'off';
            app.GroupButton_Up.Text = '⬆︎';
            app.GroupButton_Down = uibutton(app.GroupPanel, 'push');
            app.GroupButton_Down.ButtonPushedFcn = createCallbackFcn(app, @GroupButton_DownPushed, true);
            app.GroupButton_Down.Enable = 'off';
            app.GroupButton_Down.Text = '⬇︎';
            app.GroupCheckBox = uicheckbox(app.GroupPanel);
            app.GroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @GroupCheckBoxValueChanged, true);
            app.GroupCheckBox.Enable = 'off';
            app.GroupCheckBox.Text = '';
            app.GroupCheckBox.Value = true;
            app.GroupListBox = uilistbox(app.GroupPanel);
            app.GroupListBox.Items = {};
            app.GroupListBox.ValueChangedFcn = createCallbackFcn(app, @GroupListBoxValueChanged, true);
            app.GroupListBox.Value = {};
            app.RecordPanel = uipanel(app.RecordGUIFigure);
            app.RecordPanel.Title = 'Record';
            app.RecordListBox = uilistbox(app.RecordPanel);
            app.RecordListBox.Items = {};
            app.RecordListBox.ValueChangedFcn = createCallbackFcn(app, @RecordListBoxValueChanged, true);
            app.RecordListBox.Value = {};
            app.RecordButton_Add = uibutton(app.RecordPanel, 'push');
            app.RecordButton_Add.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_AddPushed, true);
            app.RecordButton_Add.Enable = 'off';
            app.RecordButton_Add.Text = 'Add';
            app.RecordButton_Remove = uibutton(app.RecordPanel, 'push');
            app.RecordButton_Remove.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_RemovePushed, true);
            app.RecordButton_Remove.Enable = 'off';
            app.RecordButton_Remove.Text = 'Remove';
            app.RecordCheckBox = uicheckbox(app.RecordPanel);
            app.RecordCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecordCheckBoxValueChanged, true);
            app.RecordCheckBox.Enable = 'off';
            app.RecordCheckBox.Text = '';
            app.RecordCheckBox.Value = true;
            app.ExperimentPanel = uipanel(app.RecordGUIFigure);
            app.ExperimentPanel.Title = 'Experiment';
            app.ExperimentButton_New = uibutton(app.ExperimentPanel, 'push');
            app.ExperimentButton_New.ButtonPushedFcn = createCallbackFcn(app, @ExperimentButton_NewPushed, true);
            app.ExperimentButton_New.Text = 'New';
            app.ExperimentButton_Load = uibutton(app.ExperimentPanel, 'push');
            app.ExperimentButton_Load.ButtonPushedFcn = createCallbackFcn(app, @ExperimentButton_LoadPushed, true);
            app.ExperimentButton_Load.Text = 'Load';
            app.ExperimentButton_Save = uibutton(app.ExperimentPanel, 'push');
            app.ExperimentButton_Save.ButtonPushedFcn = createCallbackFcn(app, @ExperimentButton_SavePushed, true);
            app.ExperimentButton_Save.Text = 'Save';
            app.ExperimentButton_SaveAs = uibutton(app.ExperimentPanel, 'push');
            app.ExperimentButton_SaveAs.ButtonPushedFcn = createCallbackFcn(app, @ExperimentButton_SaveAsPushed, true);
            app.ExperimentButton_SaveAs.Text = 'Save as';
            app.PlotDropDown = uidropdown(app.RecordGUIFigure);
            app.PlotDropDown.Items = {};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Visible = 'off';
            app.PlotDropDown.Value = {};
            app.ExportButton_Plot = uibutton(app.RecordGUIFigure, 'push');
            app.ExportButton_Plot.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_PlotPushed, true);
            app.ExportButton_Plot.Text = 'Export plot';
            app.ExportButton_Data = uibutton(app.RecordGUIFigure, 'push');
            app.ExportButton_Data.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_DataPushed, true);
            app.ExportButton_Data.Text = 'Export data';
            app.ReanalyzeButton = uibutton(app.RecordGUIFigure, 'push');
            app.ReanalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @ReanalyzeButtonPushed, true);
            app.ReanalyzeButton.Text = 'Reanalyze';
            app.OutputButton1 = uibutton(app.RecordGUIFigure, 'push');
            app.OutputButton1.ButtonPushedFcn = createCallbackFcn(app, @OutputButton1Pushed, true);
            app.OutputButton1.Visible = 'off';
            app.OutputButton1.Text = 'OutputButton1';
            app.ParametersPanel = uipanel(app.RecordGUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.ParamDisplayEditField = uieditfield(app.ParametersPanel, 'text');
            app.ParamDisplayEditField.ValueChangedFcn = createCallbackFcn(app, @ParamDisplayEditFieldValueChanged, true);
            app.AnalysisLabel = uilabel(app.ParametersPanel);
            app.AnalysisLabel.Text = 'Analysis';
            app.ParamAnalysisDropDown = uidropdown(app.ParametersPanel);
            app.ParamAnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @ParamAnalysisDropDownValueChanged, true);
            app.DisplayLabel = uilabel(app.ParametersPanel);
            app.DisplayLabel.Text = 'Display';
            app.ParamDisplayDropDown = uidropdown(app.ParametersPanel);
            app.ParamDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @ParamDisplayDropDownValueChanged, true);
            app.ParamAnalysisEditField = uieditfield(app.ParametersPanel, 'text');
            app.ParamAnalysisEditField.ValueChangedFcn = createCallbackFcn(app, @ParamAnalysisEditFieldValueChanged, true);
            app.CalculateButton1 = uibutton(app.RecordGUIFigure, 'push');
            app.CalculateButton1.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton1Pushed, true);
            app.CalculateButton1.Visible = 'off';
            app.CalculateButton1.Text = 'OutputButton1';
            % Show the figure after all components are created
            app.RecordGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecordGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RecordGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RecordGUIFigure)
        end
    end
end